classdef Vocal_Changer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel3                  matlab.ui.container.Panel
        Filter3CheckBox         matlab.ui.control.CheckBox
        Filter2CheckBox         matlab.ui.control.CheckBox
        Filter1CheckBox         matlab.ui.control.CheckBox
        Panel2                  matlab.ui.container.Panel
        CheckBox2               matlab.ui.control.CheckBox
        Label3                  matlab.ui.control.Label
        NumericEditField4       matlab.ui.control.NumericEditField
        Label2                  matlab.ui.control.Label
        NumericEditField3       matlab.ui.control.NumericEditField
        LabelNumericEditField3  matlab.ui.control.Label
        CheckBox                matlab.ui.control.CheckBox
        Panel                   matlab.ui.container.Panel
        NumericEditField2       matlab.ui.control.NumericEditField
        LabelNumericEditField2  matlab.ui.control.Label
        NumericEditField        matlab.ui.control.NumericEditField
        LabelNumericEditField   matlab.ui.control.Label
        ButtonGroup             matlab.ui.container.ButtonGroup
        RadioButton4            matlab.ui.control.RadioButton
        RadioButton3            matlab.ui.control.RadioButton
        RadioButton2            matlab.ui.control.RadioButton
        RadioButton             matlab.ui.control.RadioButton
        Button                  matlab.ui.control.Button
    end


    properties (Access = private)
        Y                 % Share input data across the app 
        FS
        Frequency1
        Frequency2
        Time1
        Time2
        File
    end

    methods (Access = private)
        
        function filtered = filtering(~,Fs, y1, f1, f2)
            Fn = Fs/2;      % Nyguist Frequency
            F1 = f1/Fn;     % Normalize frequencies 
            F2 = f2/Fn;
            
            d = fir1(300,[F1 F2],'stop'); % Design Filter 
            filtered = filter(d,1,y1);    % Apply filter
            
            %figure(1)
            %freqz(d,1,2048,Fs)            % Frequency response of filter 
            
        end
        
        function waitbar(~)
            fig = uifigure;
            bar = uiprogressdlg(fig,'Title','Please Wait');
            
            % Loading .wav
            % ...
            bar.Value = .33; 
            bar.Message = 'Loading .wav';
            pause(1)   
            
            % Creating destination file
            % ...
            bar.Value = .90; 
            bar.Message = 'Creating destination file';
            pause(1)  
        
            % Close dialog box
            close(bar)
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.Panel.Visible = 'off';
           app.Panel2.Visible = 'off';
           app.Panel3.Visible = 'off';
           app.ButtonGroup.Visible = 'off';
           
           app.RadioButton3.Visible = 'off';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [File,path] = uigetfile('*.wav'); % open a wav file
           
            [y,fs] = audioread(strcat(path,File));        % store input data
            
            x = size(y);             % get dimensions of array
            z = x(1,2);       
            app.Y = y;
            app.FS = fs;
            
            app.ButtonGroup.Visible = 'on';
            
            app.File = File;
            if z == 1           % test if the song is stereo
                errordlg('Try another file in stereo','File Error') % error message 
            end
         
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
          %  selectedButton = app.ButtonGroup.SelectedObject;
          if app.RadioButton2.Value == true        % if user selects, show next panel
              app.Panel.Visible = 'on';        
          else
               app.Panel.Visible = 'off';
          end
          
          if app.RadioButton4.Value == true        % if user selects, show next panel
              app.Panel3.Visible = 'on';        
          else
               app.Panel3.Visible = 'off';
          end     
            
          if app.RadioButton.Value == true
              left = app.Y(:,1);                % seperate each channel
              right = app.Y(:,2);
             % soundsc(left-right,app.FS)
             unvoiced = left - right;            % remove the common part from both channels
%              file = uiputfile('*.wav');   
             audiowrite(strcat("[VOCALS REMOVED] - ",app.File),unvoiced,app.FS)    % write wav file 
             app.Panel2.Visible = 'on';
          end
          
        end

        % Value changed function: NumericEditField3
        function NumericEditField3ValueChanged(app, event)
            app.Time1 = app.NumericEditField3.Value;
            
        end

        % Value changed function: NumericEditField
        function NumericEditFieldValueChanged(app, event)
            frequency1 = app.NumericEditField.Value;
            app.Frequency1 = frequency1;
            x = exist('frequency');             % if user entered a value show next panel
            if x == 1
                app.Panel2.Visible = 'on';
            end
            
        end

        % Value changed function: NumericEditField2
        function NumericEditField2ValueChanged(app, event)
            frequency2 = app.NumericEditField2.Value;
            app.Frequency2 = frequency2;
            y = exist('frequency2');            % if user entered a value show next panel
            if y == 1
                app.Panel2.Visible = 'on';
            end
            
        end

        % Value changed function: CheckBox
        function wholesongbox(app, event)
            value = app.CheckBox.Value;
            waitbar(app)
            if value == true
                clean = filtering(app,app.FS,app.Y,app.Frequency1,app.Frequency2);  % filter the song

                audiowrite(strcat("[FILTERED] ",File),clean,app.FS)    % write wav file 
            end
            
            app.CheckBox.Value = false;
            
        end

        % Value changed function: CheckBox2
        function specificpartbox(app, event)
            value = app.CheckBox2.Value;
            
            if value == true 
               x1 = app.Time1 * app.FS;        % convert time to array position
               x2 = app.Time2 * app.FS;
               ynew = app.Y(x1:x2,:);          % select the part of the song to get filtered
               
               clean = filtering(app,app.FS,ynew,app.Frequency1,app.Frequency2);  % filter the song
               y1 = app.Y(1:x1,:);                % select the beginning part of the song 
               y2 = app.Y(x2:end,:);              % select the ending part of the song
               ylast = vertcat(y1,clean,y2);      % combine the three parts 
               
%                file = uiputfile('*.wav');   
               audiowrite(strcat("[PARTLY FILTERED] ",app.File),ylast,app.FS)    % write wav file 
            end
            
        end

        % Value changed function: NumericEditField4
        function NumericEditField4ValueChanged(app, event)
            app.Time2 = app.NumericEditField4.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Select song (stereo)';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'What would you want to apply?';
            app.RadioButton = uiradiobutton(app.ButtonGroup);
            app.RadioButton.Text = 'Remove vocals';
            app.RadioButton2 = uiradiobutton(app.ButtonGroup);
            app.RadioButton2.Text = 'Lower frecuencies';
            app.RadioButton3 = uiradiobutton(app.ButtonGroup);
            app.RadioButton3.Text = 'nothere';
            app.RadioButton3.Value = true;
            app.RadioButton4 = uiradiobutton(app.ButtonGroup);
            app.RadioButton4.Text = 'Seperate vocals to change them';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Range of frequencies ';
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Starting frequency';
            app.NumericEditField = uieditfield(app.Panel, 'numeric');
            app.NumericEditField.ValueChangedFcn = createCallbackFcn(app, @NumericEditFieldValueChanged, true);
            app.LabelNumericEditField2 = uilabel(app.Panel);
            app.LabelNumericEditField2.Text = 'Ending frequency';
            app.NumericEditField2 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField2.ValueChangedFcn = createCallbackFcn(app, @NumericEditField2ValueChanged, true);
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Select time';
            app.CheckBox = uicheckbox(app.Panel2);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @wholesongbox, true);
            app.CheckBox.Text = 'Select the whole song';
            app.LabelNumericEditField3 = uilabel(app.Panel2);
            app.LabelNumericEditField3.Text = 'Select starting time in secs';
            app.NumericEditField3 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField3.ValueChangedFcn = createCallbackFcn(app, @NumericEditField3ValueChanged, true);
            app.Label2 = uilabel(app.Panel2);
            app.Label2.Text = 'Select ending time in secs';
            app.NumericEditField4 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField4.ValueChangedFcn = createCallbackFcn(app, @NumericEditField4ValueChanged, true);
            app.Label3 = uilabel(app.Panel2);
            app.Label3.Text = 'OR';
            app.CheckBox2 = uicheckbox(app.Panel2);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @specificpartbox, true);
            app.CheckBox2.Text = 'Apply to specific part of the song';
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Select Filter ';
            app.Filter1CheckBox = uicheckbox(app.Panel3);
            app.Filter1CheckBox.Text = 'Filter 1';
            app.Filter2CheckBox = uicheckbox(app.Panel3);
            app.Filter2CheckBox.Text = 'Filter 2';
            app.Filter3CheckBox = uicheckbox(app.Panel3);
            app.Filter3CheckBox.Text = 'Filter 3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vocal_Changer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end